classdef Slidip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SlidipUIFigure              matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        NextButton                  matlab.ui.control.Button
        BackButton                  matlab.ui.control.Button
        SobelthresholdSpinnerLabel  matlab.ui.control.Label
        SobelthresholdSpinner       matlab.ui.control.Spinner
        AngularstepSpinnerLabel     matlab.ui.control.Label
        AngularstepSpinner          matlab.ui.control.Spinner
        DistancestepSpinnerLabel    matlab.ui.control.Label
        DistancestepSpinner         matlab.ui.control.Spinner
        LoadImageButton             matlab.ui.control.Button
        HoughthresholdSpinnerLabel  matlab.ui.control.Label
        HoughthresholdSpinner       matlab.ui.control.Spinner
        HoughmodeDropDownLabel      matlab.ui.control.Label
        HoughmodeDropDown           matlab.ui.control.DropDown
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        State = 0; % This variable holds the current state
        MaxState = 5; % This variable represent the highest possible state
        RawImage = 0; % This variable stores the unprocessed image
        SobelImage = 0;
        HoughImage = 0;
        HoughThreshold = 0;
        FinishedImage = 0;
        VAlpha; % Vektor containing the discrete angulare steps used in the houghtrafo
        VDistance; % Vektor containing the descrete distance steps used in the houghtrafo
        LineTensor; % Description
        alphaticks = 0:20:(pi*100) % Description
    end
    
    methods (Access = private)
        % sloppy pseudo state machine
        function results = executeStateUpdate(app)
            results = 0;
            switch app.State
                case 0 % no image available
                    reset(app.UIAxes)
                    app.UIAxes.YAxis.Visible = 'off';
                    app.UIAxes.XAxis.Visible = 'off';
                    app.UIAxes.Title.String = 'No Image available';
                    app.NextButton.Enable = false;
                    app.BackButton.Enable = false;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularstepSpinner.Enable = false;
                    app.HoughthresholdSpinner.Enable = false;
                    app.DistancestepSpinner.Enable = false;
                    app.HoughmodeDropDown.Enable = false;
                    set(app.UIAxes,'color',[0 0 0])
                    imagesc(app.UIAxes, 0);
                    disp(app.State)
                    
                case 1 % image loaded
                    app.UIAxes.YAxis.Visible = 'off';
                    app.UIAxes.XAxis.Visible = 'off';
                    app.UIAxes.Title.String = 'Image Preview';
                    app.UIAxes.XLim = [0 size(app.RawImage,2)];
                    app.UIAxes.YLim = [0 size(app.RawImage,1)];
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = false;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularstepSpinner.Enable = false;
                    app.HoughthresholdSpinner.Enable = false;
                    app.DistancestepSpinner.Enable = false;
                    app.HoughmodeDropDown.Enable = false;
                    app.FormatAxis(app.RawImage, app.UIAxes);
                    imagesc(app.UIAxes, app.RawImage);
                    
                case 2 % sobel
                    app.UIAxes.Title.String = 'Sobel filter';
                    app.UIAxes.YAxis.Visible = 'off';
                    app.UIAxes.XAxis.Visible = 'off';
                    app.UIAxes.XLim = [0 size(app.RawImage,2)];
                    app.UIAxes.YLim = [0 size(app.RawImage,1)];
                    app.UIAxes.YTickMode = 'auto';                    
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = true;
                    app.SobelthresholdSpinner.Enable = true;
                    app.AngularstepSpinner.Enable = false;
                    app.DistancestepSpinner.Enable = false;
                    app.HoughthresholdSpinner.Enable = false;
                    app.HoughmodeDropDown.Enable = false;
                    app.ApplySobelOperator()
                    app.FormatAxis(app.SobelImage, app.UIAxes);
                    imagesc(app.UIAxes, app.SobelImage);
                    drawnow;
                    
                case 3 % hough trafo
                    app.UIAxes.Title.String = 'Hough transformation... Processing';
                    app.UIAxes.YAxis.Visible = 'on';
                    app.UIAxes.XAxis.Visible = 'on';                  
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = true;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularstepSpinner.Enable = true;
                    app.DistancestepSpinner.Enable = true;
                    app.HoughthresholdSpinner.Enable = false;
                    app.HoughmodeDropDown.Enable = true;
                    drawnow;
                    app.ApplyHoughTransformation();
                    app.FormatHoughTrafoAxes();                      
                    imagesc(app.UIAxes, app.HoughImage);
                    %imagesc(app.UIAxes, imresize(app.HoughImage, [(pi*100), size(app.RawImage,2)]));
                    app.UIAxes.Title.String = 'Hough transformation';     
                
                case 4 % hough threshold
                    app.UIAxes.Title.String = 'Hough Treshold';
                    app.UIAxes.YAxis.Visible = 'on';
                    app.UIAxes.XAxis.Visible = 'on';
                    app.FormatHoughTrafoAxes();
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = true;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularstepSpinner.Enable = false;
                    app.DistancestepSpinner.Enable = false;
                    app.HoughthresholdSpinner.Enable = true;
                    app.HoughmodeDropDown.Enable = false;
                    app.ApplyThreshold()
                    app.FormatHoughTrafoAxes();
                    imagesc(app.UIAxes, app.HoughThreshold);
                    
                case 5 % reverse hogh trafo                    
                    app.UIAxes.Title.String = 'Reconstructing image... Processing';
                    app.UIAxes.YAxis.Visible = 'off';
                    app.UIAxes.XAxis.Visible = 'off';
                    app.FormatAxis(app.RawImage, app.UIAxes);
                    app.UIAxes.YTickMode = 'auto';
                    app.UIAxes.YTickLabel = 'auto';
                    
                    app.NextButton.Enable = false;
                    app.BackButton.Enable = true;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularstepSpinner.Enable = false;
                    app.HoughthresholdSpinner.Enable = false;
                    app.DistancestepSpinner.Enable = false;
                    app.HoughmodeDropDown.Enable = false;
                    drawnow;
                    app.LineTensor = hough2lines( ...
                        app.HoughThreshold, ...                      
                        size(app.RawImage), ...
                        app.AngularstepSpinner.Value, ...
                        app.DistancestepSpinner.Value);
                    imagesc(app.UIAxes, app.RawImage);
                    hold(app.UIAxes, "on")
                    for k = 1 : size(app.LineTensor, 1)
                        minpoint = app.LineTensor(k, :, 1);
                        maxpoint = app.LineTensor(k, :, 2);
                        plot(app.UIAxes, [minpoint(1) maxpoint(1)], [minpoint(2), maxpoint(2)])
                    end
                    hold(app.UIAxes, "off")
                    app.UIAxes.Title.String = 'Reconstructed image';
                    
                otherwise
                    results = -1;
                    disp("unknown state")
            end
        end
        
        function picture = LoadImage(app)
            picture = 0;
            [file, path, ~] = uigetfile({'*.png', 'PNG'; '*.jpg', 'JPEG'}, "please choose a picture");
            filepath = strcat(path, file);
            if filepath ~= ""
                picture = imread(filepath);
            end
            figure(app.SlidipUIFigure)
        end
        
        function results = ApplySobelOperator(app)
            results = 0;
            value = app.SobelthresholdSpinner.Value;
            app.SobelImage = threshold(sobeloperator(rgb2bw(app.RawImage)), value);
        end
        
        function results = ApplyHoughTransformation(app)
            results = 0;
            pistep = app.AngularstepSpinner.Value;
            dstep = app.DistancestepSpinner.Value;
            if app.HoughmodeDropDown.Value == "For loop"
                [app.HoughImage, app.VDistance, app.VAlpha] = for_loop_houghtransformation(app.SobelImage, pistep, dstep);
            elseif app.HoughmodeDropDown.Value == "Tensor mode"
                try
                    [app.HoughImage, app.VDistance, app.VAlpha] = tensor_mode_houghtransformation(app.SobelImage, pistep, dstep);
                catch ME
                    if (strcmp(ME.identifier,'MATLAB:array:SizeLimitExceeded'))
                        message = "SizeLimitExceeded: Matlabs current configuration doesn't allow tensors that. Tipp: This mode is best when handling small pictures or rough settings. Consider using another mode";
                        uialert(app.SlidipUIFigure, message, "An error occurred")
                    end
                end
            end
        end
        
        function results = ApplyThreshold(app)
            results = 0;
            value = app.HoughthresholdSpinner.Value;
            app.HoughThreshold = threshold(app.HoughImage, value);
        end
        
        function results = FormatAxis(~,Image, UIaxes)            
            UIaxes.XLim = [0.5 size(Image,2)+0.5];           
            UIaxes.YLim = [0.5 size(Image,1)+0.5];
        end
        
        
        function results = FormatHoughTrafoAxes(app)
            results = 0;
            reset(app.UIAxes)
            app.FormatAxis(app.HoughImage, app.UIAxes);
            app.UIAxes.XLabel.String = 'Distance d from origin';
            app.UIAxes.XTickMode = 'manual';  
            app.UIAxes.XTickLabelMode = 'manual';
            app.UIAxes.XTick = linspace(1, size(app.HoughImage,2), 3);
            app.UIAxes.XTickLabel = ({app.VDistance(1) 0 app.VDistance(end)});
            app.UIAxes.YLabel.String = 'Angle from left border counterclockwise in rad';
            app.UIAxes.YTickMode = 'manual';  
            app.UIAxes.YTickLabelMode = 'manual';
            app.UIAxes.YTick = linspace(1, size(app.HoughImage, 1), 5);
            app.UIAxes.YTickLabel = ({'0','\pi/4','\pi/2','3\pi/4','\pi'});
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AppStatup(app)
            app.executeStateUpdate()
        end

        % Button pushed function: NextButton
        function BNextPushed(app, event)
            if app.State < app.MaxState
                app.State = app.State + 1;
            end
            app.executeStateUpdate();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SlidipUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {580, 580};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {279, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: BackButton
        function BBackPushed(app, event)
            if app.State > 0
                app.State = app.State - 1;
            end
            app.executeStateUpdate();
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
           app.RawImage = app.LoadImage();
           app.State = 1;
           if app.RawImage == 0
               app.State = 0;
           end
           app.executeStateUpdate();
        end

        % Value changed function: SobelthresholdSpinner
        function SobelThresholdChanged(app, event)
            app.executeStateUpdate()
        end

        % Value changed function: AngularstepSpinner
        function AngularStepRadChanging(app, event)
            app.executeStateUpdate();
            
        end

        % Value changed function: DistancestepSpinner
        function PixelStep(app, event)
            app.executeStateUpdate();            
        end

        % Value changed function: HoughthresholdSpinner
        function HoughThresholdChanging(app, event)
            app.executeStateUpdate();            
        end

        % Value changed function: HoughmodeDropDown
        function HoughModeChanged(app, event)
            app.executeStateUpdate();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SlidipUIFigure = uifigure('Visible', 'off');
            app.SlidipUIFigure.Name = 'Slidip';
            app.SlidipUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SlidipUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.NextButton = uibutton(app.GridLayout2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @BNextPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.GridLayout2, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BBackPushed, true);
            app.BackButton.Text = 'Back';
            app.SobelthresholdSpinnerLabel = uilabel(app.GridLayout2);
            app.SobelthresholdSpinnerLabel.Text = 'Sobel threshold';
            app.SobelthresholdSpinner = uispinner(app.GridLayout2);
            app.SobelthresholdSpinner.Step = 0.01;
            app.SobelthresholdSpinner.Limits = [0 1];
            app.SobelthresholdSpinner.ValueDisplayFormat = '%.2f';
            app.SobelthresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @SobelThresholdChanged, true);
            app.SobelthresholdSpinner.Value = 0.5;
            app.AngularstepSpinnerLabel = uilabel(app.GridLayout2);
            app.AngularstepSpinnerLabel.Text = 'Angular step';
            app.AngularstepSpinner = uispinner(app.GridLayout2);
            app.AngularstepSpinner.Step = 0.01;
            app.AngularstepSpinner.Limits = [0.01 1];
            app.AngularstepSpinner.ValueDisplayFormat = '%.2f';
            app.AngularstepSpinner.ValueChangedFcn = createCallbackFcn(app, @AngularStepRadChanging, true);
            app.AngularstepSpinner.Value = 0.01;
            app.DistancestepSpinnerLabel = uilabel(app.GridLayout2);
            app.DistancestepSpinnerLabel.Text = {'Distance step'; ''};
            app.DistancestepSpinner = uispinner(app.GridLayout2);
            app.DistancestepSpinner.Limits = [0.1 20];
            app.DistancestepSpinner.ValueChangedFcn = createCallbackFcn(app, @PixelStep, true);
            app.DistancestepSpinner.Value = 10;
            app.LoadImageButton = uibutton(app.GridLayout2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.HoughthresholdSpinnerLabel = uilabel(app.GridLayout2);
            app.HoughthresholdSpinnerLabel.Text = 'Hough threshold';
            app.HoughthresholdSpinner = uispinner(app.GridLayout2);
            app.HoughthresholdSpinner.Step = 0.01;
            app.HoughthresholdSpinner.Limits = [0 1];
            app.HoughthresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @HoughThresholdChanging, true);
            app.HoughthresholdSpinner.Value = 0.6;
            app.HoughmodeDropDownLabel = uilabel(app.GridLayout2);
            app.HoughmodeDropDownLabel.Text = 'Hough mode';
            app.HoughmodeDropDown = uidropdown(app.GridLayout2);
            app.HoughmodeDropDown.Items = {'For loop', 'Tensor mode'};
            app.HoughmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @HoughModeChanged, true);
            app.HoughmodeDropDown.Value = 'For loop';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.SlidipUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Slidip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SlidipUIFigure)

            % Execute the startup function
            runStartupFcn(app, @AppStatup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SlidipUIFigure)
        end
    end
end